classdef appMeasureCalculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasureCalculationFigure     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        DatenbankTab                 matlab.ui.container.Tab
        DatenbankAuswhlenButton      matlab.ui.control.Button
        DatabaseTable                matlab.ui.control.Table
        FehlendeKoordinatenTab       matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        KennzeichentragerLabel       matlab.ui.control.Label
        x1Kennzeichentrager          matlab.ui.control.NumericEditField
        x1Label                      matlab.ui.control.Label
        RadplatteLabel               matlab.ui.control.Label
        x1Radplatte                  matlab.ui.control.NumericEditField
        x1Label_2                    matlab.ui.control.Label
        x1Pedal                      matlab.ui.control.NumericEditField
        x1Label_3                    matlab.ui.control.Label
        x1Abtrieb                    matlab.ui.control.NumericEditField
        x1Label_4                    matlab.ui.control.Label
        PedalLabel                   matlab.ui.control.Label
        AbtriebLabel                 matlab.ui.control.Label
        y1Kennzeichentrager          matlab.ui.control.NumericEditField
        y1Label                      matlab.ui.control.Label
        z1Kennzeichentrager          matlab.ui.control.NumericEditField
        z1Label                      matlab.ui.control.Label
        y1Radplatte                  matlab.ui.control.NumericEditField
        y1Label_2                    matlab.ui.control.Label
        z1Radplatte                  matlab.ui.control.NumericEditField
        z1Label_2                    matlab.ui.control.Label
        y1Pedal                      matlab.ui.control.NumericEditField
        y1Label_3                    matlab.ui.control.Label
        z1Pedal                      matlab.ui.control.NumericEditField
        z1Label_3                    matlab.ui.control.Label
        y1Abtrieb                    matlab.ui.control.NumericEditField
        y1Label_4                    matlab.ui.control.Label
        z1Abtrieb                    matlab.ui.control.NumericEditField
        z1Label_4                    matlab.ui.control.Label
        FahrzeugLabel                matlab.ui.control.Label
        KennzeichentragerDropDown    matlab.ui.control.DropDown
        FahrzeugLabel_2              matlab.ui.control.Label
        RadplatteDropDown            matlab.ui.control.DropDown
        FahrzeugLabel_4              matlab.ui.control.Label
        PedalDropDown                matlab.ui.control.DropDown
        FahrzeugLabel_3              matlab.ui.control.Label
        AbtriebDropDown              matlab.ui.control.DropDown
        AttributesKennzeichentrager  matlab.ui.control.Button
        AttributesRadplatte          matlab.ui.control.Button
        AttributesPedal              matlab.ui.control.Button
        AttributesAbtrieb            matlab.ui.control.Button
        NewKennzeichentrager         matlab.ui.control.Button
        NewRadplatte                 matlab.ui.control.Button
        NewPedal                     matlab.ui.control.Button
        NewAbtrieb                   matlab.ui.control.Button
        CoordinatesTable             matlab.ui.control.Table
        KoordinateLschenButton       matlab.ui.control.Button
        AnbindungAbgasanlageXRichtungLabel  matlab.ui.control.Label
        x1Exhaust                    matlab.ui.control.NumericEditField
        x1Label_5                    matlab.ui.control.Label
        y1Exhaust                    matlab.ui.control.NumericEditField
        y1Label_5                    matlab.ui.control.Label
        z1Exhaust                    matlab.ui.control.NumericEditField
        z1Label_5                    matlab.ui.control.Label
        AttributesExhaust            matlab.ui.control.Button
        NewExhaust                   matlab.ui.control.Button
        LicenceDropDown_2Label       matlab.ui.control.Label
        ExhaustDropDown              matlab.ui.control.DropDown
        AnbindungAbgasanlageYRichtungLabel  matlab.ui.control.Label
        x1Abgasanlage                matlab.ui.control.NumericEditField
        x1Label_6                    matlab.ui.control.Label
        y1Abgasanlage                matlab.ui.control.NumericEditField
        y1Label_6                    matlab.ui.control.Label
        z1Abgasanlage                matlab.ui.control.NumericEditField
        z1Label_6                    matlab.ui.control.Label
        AttributesAbgasanlage        matlab.ui.control.Button
        NewAbgasanlage               matlab.ui.control.Button
        LicenceDropDown_3Label       matlab.ui.control.Label
        AbgasanlageDropDown          matlab.ui.control.DropDown
        AnbindungBKVLabel            matlab.ui.control.Label
        x1BKV                        matlab.ui.control.NumericEditField
        x1Label_7                    matlab.ui.control.Label
        y1BKV                        matlab.ui.control.NumericEditField
        y1Label_7                    matlab.ui.control.Label
        z1BKV                        matlab.ui.control.NumericEditField
        z1Label_7                    matlab.ui.control.Label
        AttributesBKV                matlab.ui.control.Button
        NewBKV                       matlab.ui.control.Button
        LicenceDropDown_4Label       matlab.ui.control.Label
        BKVDropDown                  matlab.ui.control.DropDown
        AnbindungSQTzuCMSLabel       matlab.ui.control.Label
        x1SQT                        matlab.ui.control.NumericEditField
        x1Label_8                    matlab.ui.control.Label
        y1SQT                        matlab.ui.control.NumericEditField
        y1Label_8                    matlab.ui.control.Label
        z1SQT                        matlab.ui.control.NumericEditField
        z1Label_8                    matlab.ui.control.Label
        AttributesSQT                matlab.ui.control.Button
        NewSQT                       matlab.ui.control.Button
        LicenceDropDown_5Label       matlab.ui.control.Label
        SQTDropDown                  matlab.ui.control.DropDown
        AnbindungCMSzuLTLabel        matlab.ui.control.Label
        x1CMS                        matlab.ui.control.NumericEditField
        x1Label_9                    matlab.ui.control.Label
        y1CMS                        matlab.ui.control.NumericEditField
        y1Label_9                    matlab.ui.control.Label
        z1CMS                        matlab.ui.control.NumericEditField
        z1Label_9                    matlab.ui.control.Label
        AttributesCMS                matlab.ui.control.Button
        NewCMS                       matlab.ui.control.Button
        LicenceDropDown_6Label       matlab.ui.control.Label
        CMSDropDown                  matlab.ui.control.DropDown
        MotorGetriebeflanschLabel    matlab.ui.control.Label
        x1Getriebeflansch            matlab.ui.control.NumericEditField
        x1Label_10                   matlab.ui.control.Label
        y1Getriebeflansch            matlab.ui.control.NumericEditField
        y1Label_10                   matlab.ui.control.Label
        z1Getriebeflansch            matlab.ui.control.NumericEditField
        z1Label_10                   matlab.ui.control.Label
        AttributesGetriebeflansch    matlab.ui.control.Button
        NewGetriebeflansch           matlab.ui.control.Button
        LicenceDropDown_7Label       matlab.ui.control.Label
        GetriebeflanschDropDown      matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        umfehlendeKoordinatenzuspeichernLabel  matlab.ui.control.Label
        BerechnungTab                matlab.ui.container.Tab
        BerechnungStartenButton      matlab.ui.control.Button
        CalculationTable             matlab.ui.control.Table
        BerechneteWerteLabel         matlab.ui.control.Label
        RefreshTabelleButton         matlab.ui.control.Button
        TotalValuesLabel             matlab.ui.control.Label
    end


    properties (Access = public)
            
        selected_cells=[]; 

    end


    methods (Access = private)

        % Code that executes after component creation
        function openingFCN(app)
         
            path = which('appMeasureCalculation');
            slash_id = strfind(path, '\');
            path(slash_id(end):end) = [];
            cd(path);
            %fcn_jump_to_path('appMeasureCalculation');
            cd ..\..
            currentfolder = pwd;
            addpath(genpath(currentfolder));
            sqlite_path = which('sqlite-jdbc-3.8.6.jar');
            javaaddpath(sqlite_path);
           fcn_get_vehicles(app);
           fcn_load_missing_coordinates_table(app);
        
        end

        % Button pushed function: DatenbankAuswhlenButton
        function DatenbankAuswhlenButtonPushed(app, event)
            fcn_load_database();
            fcn_fill_database_table(app);
        end

        % Button pushed function: AttributesKennzeichentrager
        function AttributesKennzeichentragerButtonPushed(app, event)
            fcn_set_values(app,'Kennzeichentrager');
           fcn_set_attribute_type('Kennzeichentrager');
            attributesGUI();
            
           
        end

        % Button pushed function: AttributesRadplatte
        function AttributesRadplatteButtonPushed(app, event)
             fcn_set_values(app,'Radplatte');
             fcn_set_attribute_type('Radplatte');
             attributesGUI();
           
        end

        % Button pushed function: AttributesPedal
        function AttributesPedalButtonPushed(app, event)
           fcn_set_values(app,'Pedal');
           fcn_set_attribute_type('Pedal');
           attributesGUI();
            
        end

        % Button pushed function: AttributesAbtrieb
        function AttributesAbtriebButtonPushed(app, event)
             fcn_set_values(app,'Abtrieb');
             fcn_set_attribute_type('Abtrieb');
             attributesGUI();
       
        end

        % Button pushed function: KoordinateLschenButton
        function KoordinateLschenButtonPushed(app, event)
            fcn_delete_coordinate(app);
        end

        % Cell selection callback: CoordinatesTable
        function TableCellSelection(app, event)
            app.selected_cells = event.Indices;
        end

        % Button pushed function: NewKennzeichentrager
        function NewKennzeichentragerButtonPushed(app, event)
            fcn_add_new_coordinate('Kennzeichentrager');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'Kennzeichentrager');
        end

        % Button pushed function: NewRadplatte
        function NewRadplatteButtonPushed(app, event)
           fcn_add_new_coordinate('Radplatte');
           fcn_load_missing_coordinates_table(app);
           fcn_reset_missing_coordinates(app,'Radplatte');
         
        end

        % Button pushed function: NewPedal
        function NewPedalButtonPushed(app, event)
            fcn_add_new_coordinate('Pedal');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'Pedal');
        end

        % Button pushed function: NewAbtrieb
        function NewAbtriebButtonPushed(app, event)
           fcn_add_new_coordinate('Abtrieb');
           fcn_load_missing_coordinates_table(app);
           fcn_reset_missing_coordinates(app,'Abtrieb');
        end

        % Button pushed function: BerechnungStartenButton
        function BerechnungStartenButtonPushed(app, event)
            fcn_start_calculation();
            
        end

        % Button pushed function: RefreshTabelleButton
        function RefreshTabelleButtonPushed(app, event)
            fcn_fill_calculation_table(app);
        end

        % Button pushed function: AttributesExhaust
        function AttributesExhaustButtonPushed(app, event)
            fcn_set_values(app,'Exhaust');
             fcn_set_attribute_type('Exhaust');
             attributesGUI();
        end

        % Button pushed function: AttributesAbgasanlage
        function AttributesAbgasanlageButtonPushed(app, event)
            fcn_set_values(app,'Abgasanlage');
             fcn_set_attribute_type('Abgasanlage');
             attributesGUI();
        end

        % Button pushed function: NewAbgasanlage
        function NewAbgasanlageButtonPushed(app, event)
            fcn_add_new_coordinate('Abgasanlage');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'Abgasanlage');
        end

        % Button pushed function: AttributesBKV
        function AttributesBKVButtonPushed(app, event)
            fcn_set_values(app,'BKV');
             fcn_set_attribute_type('BKV');
             attributesGUI();
        end

        % Button pushed function: NewBKV
        function NewBKVButtonPushed(app, event)
            fcn_add_new_coordinate('BKV');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'BKV');
        end

        % Button pushed function: AttributesSQT
        function AttributesSQTButtonPushed(app, event)
            fcn_set_values(app,'SQT');
             fcn_set_attribute_type('SQT');
             attributesGUI();
        end

        % Button pushed function: NewSQT
        function NewSQTButtonPushed(app, event)
            fcn_add_new_coordinate('SQT');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'SQT');
        end

        % Button pushed function: AttributesCMS
        function AttributesCMSButtonPushed(app, event)
            fcn_set_values(app,'CMS');
             fcn_set_attribute_type('CMS');
             attributesGUI();
        end

        % Button pushed function: NewCMS
        function NewCMSButtonPushed(app, event)
            fcn_add_new_coordinate('CMS');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'CMS');
        end

        % Button pushed function: AttributesGetriebeflansch
        function AttributesGetriebeflanschButtonPushed(app, event)
            fcn_set_values(app,'Getriebeflansch');
             fcn_set_attribute_type('Getriebeflansch');
             attributesGUI();
        end

        % Button pushed function: NewGetriebeflansch
        function NewGetriebeflanschButtonPushed(app, event)
            fcn_add_new_coordinate('Getriebeflansch');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'Getriebeflansch');
        end

        % Button pushed function: NewExhaust
        function NewExhaustButtonPushed(app, event)
            fcn_add_new_coordinate('Exhaust');
            fcn_load_missing_coordinates_table(app);
            fcn_reset_missing_coordinates(app,'Exhaust');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasureCalculationFigure = uifigure;
            app.MeasureCalculationFigure.Name = 'Measure Calculation';
            setAutoResize(app, app.MeasureCalculationFigure, true)
            app.Panel = uipanel(app.MeasureCalculationFigure);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.Icon = 'Logo.PNG';
            app.Button.Text = '';
            app.TabGroup = uitabgroup(app.MeasureCalculationFigure);
            app.DatenbankTab = uitab(app.TabGroup);
            app.DatenbankTab.Title = 'Datenbank';
            app.DatenbankTab.Units = 'pixels';
            app.DatenbankAuswhlenButton = uibutton(app.DatenbankTab, 'push');
            app.DatenbankAuswhlenButton.ButtonPushedFcn = createCallbackFcn(app, @DatenbankAuswhlenButtonPushed, true);
            app.DatenbankAuswhlenButton.Text = 'Datenbank Auswählen';
            app.DatabaseTable = uitable(app.DatenbankTab);
            app.DatabaseTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DatabaseTable.RowName = {};
            app.FehlendeKoordinatenTab = uitab(app.TabGroup);
            app.FehlendeKoordinatenTab.Title = 'Fehlende Koordinaten';
            app.FehlendeKoordinatenTab.Units = 'pixels';
            app.Label = uilabel(app.FehlendeKoordinatenTab);
            app.Label.Text = 'Bitte füllen Sie zuerst die Werte (x1, y1, z1) aus und klicken Sie dann ';
            app.KennzeichentragerLabel = uilabel(app.FehlendeKoordinatenTab);
            app.KennzeichentragerLabel.Text = 'Kennzeichentrager';
            app.x1Kennzeichentrager = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label.Text = 'x1';
            app.RadplatteLabel = uilabel(app.FehlendeKoordinatenTab);
            app.RadplatteLabel.Text = 'Radplatte';
            app.x1Radplatte = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_2 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_2.Text = 'x1';
            app.x1Pedal = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_3 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_3.Text = 'x1';
            app.x1Abtrieb = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_4 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_4.Text = 'x1';
            app.PedalLabel = uilabel(app.FehlendeKoordinatenTab);
            app.PedalLabel.Text = 'Pedal';
            app.AbtriebLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AbtriebLabel.Text = 'Abtrieb';
            app.y1Kennzeichentrager = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label.Text = 'y1';
            app.z1Kennzeichentrager = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label.Text = 'z1';
            app.y1Radplatte = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_2 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_2.Text = 'y1';
            app.z1Radplatte = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_2 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_2.Text = 'z1';
            app.y1Pedal = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_3 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_3.Text = 'y1';
            app.z1Pedal = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_3 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_3.Text = 'z1';
            app.y1Abtrieb = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_4 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_4.Text = 'y1';
            app.z1Abtrieb = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_4 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_4.Text = 'z1';
            app.FahrzeugLabel = uilabel(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel.Text = 'Fahrzeug';
            app.KennzeichentragerDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_2 = uilabel(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_2.Text = 'Fahrzeug';
            app.RadplatteDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_4 = uilabel(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_4.Text = 'Fahrzeug';
            app.PedalDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_3 = uilabel(app.FehlendeKoordinatenTab);
            app.FahrzeugLabel_3.Text = 'Fahrzeug';
            app.AbtriebDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.AttributesKennzeichentrager = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesKennzeichentrager.ButtonPushedFcn = createCallbackFcn(app, @AttributesKennzeichentragerButtonPushed, true);
            app.AttributesKennzeichentrager.Text = 'Attributen';
            app.AttributesRadplatte = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesRadplatte.ButtonPushedFcn = createCallbackFcn(app, @AttributesRadplatteButtonPushed, true);
            app.AttributesRadplatte.Text = 'Attributen';
            app.AttributesPedal = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesPedal.ButtonPushedFcn = createCallbackFcn(app, @AttributesPedalButtonPushed, true);
            app.AttributesPedal.Text = 'Attributen';
            app.AttributesAbtrieb = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesAbtrieb.ButtonPushedFcn = createCallbackFcn(app, @AttributesAbtriebButtonPushed, true);
            app.AttributesAbtrieb.Text = 'Attributen';
            app.NewKennzeichentrager = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewKennzeichentrager.ButtonPushedFcn = createCallbackFcn(app, @NewKennzeichentragerButtonPushed, true);
            app.NewKennzeichentrager.Text = 'Hinzufügen';
            app.NewRadplatte = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewRadplatte.ButtonPushedFcn = createCallbackFcn(app, @NewRadplatteButtonPushed, true);
            app.NewRadplatte.Text = 'Hinzufügen';
            app.NewPedal = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewPedal.ButtonPushedFcn = createCallbackFcn(app, @NewPedalButtonPushed, true);
            app.NewPedal.Text = 'Hinzufügen';
            app.NewAbtrieb = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewAbtrieb.ButtonPushedFcn = createCallbackFcn(app, @NewAbtriebButtonPushed, true);
            app.NewAbtrieb.Text = 'Hinzufügen';
            app.CoordinatesTable = uitable(app.FehlendeKoordinatenTab);
            app.CoordinatesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CoordinatesTable.RowName = {};
            app.CoordinatesTable.CellSelectionCallback = createCallbackFcn(app, @TableCellSelection, true);
            app.KoordinateLschenButton = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.KoordinateLschenButton.ButtonPushedFcn = createCallbackFcn(app, @KoordinateLschenButtonPushed, true);
            app.KoordinateLschenButton.Text = 'Koordinate Löschen';
            app.AnbindungAbgasanlageXRichtungLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AnbindungAbgasanlageXRichtungLabel.Text = 'Anbindung Abgasanlage X-Richtung ';
            app.x1Exhaust = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_5 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_5.Text = 'x1';
            app.y1Exhaust = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_5 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_5.Text = 'y1';
            app.z1Exhaust = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_5 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_5.Text = 'z1';
            app.AttributesExhaust = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesExhaust.ButtonPushedFcn = createCallbackFcn(app, @AttributesExhaustButtonPushed, true);
            app.AttributesExhaust.Text = 'Attributen';
            app.NewExhaust = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewExhaust.ButtonPushedFcn = createCallbackFcn(app, @NewExhaustButtonPushed, true);
            app.NewExhaust.Text = 'Hinzufügen';
            app.LicenceDropDown_2Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_2Label.Text = 'Fahrzeug';
            app.ExhaustDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.AnbindungAbgasanlageYRichtungLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AnbindungAbgasanlageYRichtungLabel.Text = 'Anbindung Abgasanlage Y-Richtung ';
            app.x1Abgasanlage = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_6 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_6.Text = 'x1';
            app.y1Abgasanlage = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_6 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_6.Text = 'y1';
            app.z1Abgasanlage = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_6 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_6.Text = 'z1';
            app.AttributesAbgasanlage = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesAbgasanlage.ButtonPushedFcn = createCallbackFcn(app, @AttributesAbgasanlageButtonPushed, true);
            app.AttributesAbgasanlage.Text = 'Attributen';
            app.NewAbgasanlage = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewAbgasanlage.ButtonPushedFcn = createCallbackFcn(app, @NewAbgasanlageButtonPushed, true);
            app.NewAbgasanlage.Text = 'Hinzufügen';
            app.LicenceDropDown_3Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_3Label.Text = 'Fahrzeug';
            app.AbgasanlageDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.AnbindungBKVLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AnbindungBKVLabel.Text = 'Anbindung BKV';
            app.x1BKV = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_7 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_7.Text = 'x1';
            app.y1BKV = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_7 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_7.Text = 'y1';
            app.z1BKV = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_7 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_7.Text = 'z1';
            app.AttributesBKV = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesBKV.ButtonPushedFcn = createCallbackFcn(app, @AttributesBKVButtonPushed, true);
            app.AttributesBKV.Text = 'Attributen';
            app.NewBKV = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewBKV.ButtonPushedFcn = createCallbackFcn(app, @NewBKVButtonPushed, true);
            app.NewBKV.Text = 'Hinzufügen';
            app.LicenceDropDown_4Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_4Label.Text = 'Fahrzeug';
            app.BKVDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.AnbindungSQTzuCMSLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AnbindungSQTzuCMSLabel.Text = 'Anbindung SQT zu CMS ';
            app.x1SQT = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_8 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_8.Text = 'x1';
            app.y1SQT = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_8 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_8.Text = 'y1';
            app.z1SQT = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_8 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_8.Text = 'z1';
            app.AttributesSQT = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesSQT.ButtonPushedFcn = createCallbackFcn(app, @AttributesSQTButtonPushed, true);
            app.AttributesSQT.Text = 'Attributen';
            app.NewSQT = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewSQT.ButtonPushedFcn = createCallbackFcn(app, @NewSQTButtonPushed, true);
            app.NewSQT.Text = 'Hinzufügen';
            app.LicenceDropDown_5Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_5Label.Text = 'Fahrzeug';
            app.SQTDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.AnbindungCMSzuLTLabel = uilabel(app.FehlendeKoordinatenTab);
            app.AnbindungCMSzuLTLabel.Text = 'Anbindung CMS zu LT';
            app.x1CMS = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_9 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_9.Text = 'x1';
            app.y1CMS = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_9 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_9.Text = 'y1';
            app.z1CMS = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_9 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_9.Text = 'z1';
            app.AttributesCMS = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesCMS.ButtonPushedFcn = createCallbackFcn(app, @AttributesCMSButtonPushed, true);
            app.AttributesCMS.Text = 'Attributen';
            app.NewCMS = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewCMS.ButtonPushedFcn = createCallbackFcn(app, @NewCMSButtonPushed, true);
            app.NewCMS.Text = 'Hinzufügen';
            app.LicenceDropDown_6Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_6Label.Text = 'Fahrzeug';
            app.CMSDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.MotorGetriebeflanschLabel = uilabel(app.FehlendeKoordinatenTab);
            app.MotorGetriebeflanschLabel.Text = 'Motor-Getriebeflansch';
            app.x1Getriebeflansch = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.x1Label_10 = uilabel(app.FehlendeKoordinatenTab);
            app.x1Label_10.Text = 'x1';
            app.y1Getriebeflansch = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.y1Label_10 = uilabel(app.FehlendeKoordinatenTab);
            app.y1Label_10.Text = 'y1';
            app.z1Getriebeflansch = uieditfield(app.FehlendeKoordinatenTab, 'numeric');
            app.z1Label_10 = uilabel(app.FehlendeKoordinatenTab);
            app.z1Label_10.Text = 'z1';
            app.AttributesGetriebeflansch = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.AttributesGetriebeflansch.ButtonPushedFcn = createCallbackFcn(app, @AttributesGetriebeflanschButtonPushed, true);
            app.AttributesGetriebeflansch.Text = 'Attributen';
            app.NewGetriebeflansch = uibutton(app.FehlendeKoordinatenTab, 'push');
            app.NewGetriebeflansch.ButtonPushedFcn = createCallbackFcn(app, @NewGetriebeflanschButtonPushed, true);
            app.NewGetriebeflansch.Text = 'Hinzufügen';
            app.LicenceDropDown_7Label = uilabel(app.FehlendeKoordinatenTab);
            app.LicenceDropDown_7Label.Text = 'Fahrzeug';
            app.GetriebeflanschDropDown = uidropdown(app.FehlendeKoordinatenTab);
            app.Label_2 = uilabel(app.FehlendeKoordinatenTab);
            app.Label_2.Text = 'auf die zusätzlichen Attribute. Klicken Sie dann auf Speichern,';
            app.umfehlendeKoordinatenzuspeichernLabel = uilabel(app.FehlendeKoordinatenTab);
            app.umfehlendeKoordinatenzuspeichernLabel.Text = ' um fehlende Koordinaten zu speichern.';
            app.BerechnungTab = uitab(app.TabGroup);
            app.BerechnungTab.Title = 'Berechnung';
            app.BerechnungTab.Units = 'pixels';
            app.BerechnungStartenButton = uibutton(app.BerechnungTab, 'push');
            app.BerechnungStartenButton.ButtonPushedFcn = createCallbackFcn(app, @BerechnungStartenButtonPushed, true);
            app.BerechnungStartenButton.Text = 'Berechnung Starten';
            app.CalculationTable = uitable(app.BerechnungTab);
            app.CalculationTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CalculationTable.RowName = {};
            app.BerechneteWerteLabel = uilabel(app.BerechnungTab);
            app.BerechneteWerteLabel.Text = 'Berechnete Werte';
            app.RefreshTabelleButton = uibutton(app.BerechnungTab, 'push');
            app.RefreshTabelleButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshTabelleButtonPushed, true);
            app.RefreshTabelleButton.Text = 'Refresh Tabelle';
            app.TotalValuesLabel = uilabel(app.BerechnungTab);
            app.TotalValuesLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appMeasureCalculation()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasureCalculationFigure)

            % Execute the startup function
            runStartupFcn(app, @openingFCN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasureCalculationFigure)
        end
    end
end