classdef attributesAll < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AttributeDropDownLabel  matlab.ui.control.Label
        AttributeDropDown       matlab.ui.control.DropDown
        ValueDropDownLabel      matlab.ui.control.Label
        ValueDropDown           matlab.ui.control.DropDown
        AddButton               matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        Label                   matlab.ui.control.Label
    end


    properties (Access = public)
       attribute_type=[];
        licence_attributes = cell(1,48);
        wheel_attributes=cell(1,48);
        pedal_attributes=cell(1,48);
        down_attributes=cell(1,48);
        values;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fcn_get_Attributes(app);
        end

        % Button pushed function: AddButton
        function AddPushed(app, event)
         %    msgbox('Attribute added');
             attr = app.AttributeDropDown.Value;
             val = app.ValueDropDown.Value;
             fcn_set_coordinateAttributes(app,attr,val);
             msgbox('Attribute Added!');
             
        end

        % Button pushed function: SaveButton
        function SavePushed(app, event)
            fcn_save_Attributes(app); 
            msgbox('Attributes Saved');
        end

        % Value changed function: AttributeDropDown
        function AttributeDropDownValueChanged(app, event)
            value = app.AttributeDropDown.Value;
            value_matrix = fcn_get_valueAttributes(value);
            app.ValueDropDown.Items = value_matrix;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.AttributeDropDownLabel = uilabel(app.UIFigure);
            app.AttributeDropDownLabel.Text = 'Attribute';
            app.AttributeDropDown = uidropdown(app.UIFigure);
            app.AttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @AttributeDropDownValueChanged, true);
            app.ValueDropDownLabel = uilabel(app.UIFigure);
            app.ValueDropDownLabel.Text = 'Value';
            app.ValueDropDown = uidropdown(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddPushed, true);
            app.AddButton.Text = 'Add';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Please Select Attributes by selecting click "Add". You can save it with "Save" button';
        end
    end

    methods (Access = public)

        % Construct app
        function app = attributesAll()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end