classdef attributesWheel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        attributeTable      matlab.ui.control.Table
        PleaseSelectAttributesbyclickingtherowsLabel  matlab.ui.control.Label
        SaveAttr            matlab.ui.control.Button
        AddAttributeButton  matlab.ui.control.Button
    end


    properties (Access = public)
        data;
        values ;
        attributes = cell(1,42);
        selected_cells=[]; % Description
    end

    methods (Access = public)
    
        function outcome_arr = getValues(app)
            outcome_arr = app.values;
        end
        function [] = setValues(income_arr)
            app.values = income_arr;
            disp(app.values);
        end
         
    end

    methods (Access = private)

        % Code that executes after component creation
        function openingFCN(app)
          
            update_global_attributes();
           %Code for prepare table columns
           load('ws_global_attributes.mat');
           columns = glo_names_all(:,2);
           columns = columns(11:end,:);
            app.attributeTable.ColumnName = columns;
           %Code for prepare table data 
            app.data = glo_raw_meas;
            app.data = app.data(:,2:end);
            app.data = app.data(14:end,:);
            for i=1:numel(app.data)
                if isnan(app.data{i})
                   app.data{i}='';
                end
            end
           app.data=app.data(:,2:end);
            app.data = app.data';
            
            app.attributeTable.Data = app.data;
            
        end

        % Button pushed function: AddAttributeButton
        function addAttribute(app, event)
         %code for adding attributes
            if(~isempty(app.selected_cells))
                if(size(app.selected_cells(1)) ==1)
                    row = app.selected_cells(1);
                    col = app.selected_cells(2);
                    app.attributes{1,col} = app.data(row,col);
                    msgbox('Attribute added');
                else
                     msgbox('ERROR');
                end
            else
                msgbox('ERROR');
            end
        
        end

        % Cell selection callback: attributeTable
        function SelectedCell(app, event)
            app.selected_cells = event.Indices;
           % disp(selected_cells);
        end

        % Button pushed function: SaveAttr
        function SaveAttributes(app, event)
            wheel_matrix = [app.values, app.attributes];          
            var_filename = 'ws_wheel_matrix.mat';
            save(var_filename, 'wheel_matrix');          
            msgbox('Attributes saved in Matlab');
            return;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.attributeTable = uitable(app.UIFigure);
            app.attributeTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.attributeTable.RowName = {};
            app.attributeTable.CellSelectionCallback = createCallbackFcn(app, @SelectedCell, true);
            app.PleaseSelectAttributesbyclickingtherowsLabel = uilabel(app.UIFigure);
            app.PleaseSelectAttributesbyclickingtherowsLabel.Text = 'Please Select Attributes by clicking the rows then click Add Attribute';
            app.SaveAttr = uibutton(app.UIFigure, 'push');
            app.SaveAttr.ButtonPushedFcn = createCallbackFcn(app, @SaveAttributes, true);
            app.SaveAttr.Text = 'Save Attributes';
            app.AddAttributeButton = uibutton(app.UIFigure, 'push');
            app.AddAttributeButton.ButtonPushedFcn = createCallbackFcn(app, @addAttribute, true);
            app.AddAttributeButton.Text = 'Add Attribute';
        end
    end

    methods (Access = public)

        % Construct app
        function app = attributesWheel()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @openingFCN)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end