classdef newCoordinate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddNewCoordinateUIFigure  matlab.ui.Figure
        value                     matlab.ui.control.NumericEditField
        x1                        matlab.ui.control.NumericEditField
        x2                        matlab.ui.control.NumericEditField
        y1                        matlab.ui.control.NumericEditField
        y2                        matlab.ui.control.NumericEditField
        z1                        matlab.ui.control.NumericEditField
        z2                        matlab.ui.control.NumericEditField
        ValueLabel                matlab.ui.control.Label
        x1Label                   matlab.ui.control.Label
        x2Label                   matlab.ui.control.Label
        y1Label                   matlab.ui.control.Label
        y2Label                   matlab.ui.control.Label
        z1Label                   matlab.ui.control.Label
        z2Label                   matlab.ui.control.Label
        AddButton                 matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        AttributeDropDownLabel    matlab.ui.control.Label
        AttributeDropDown         matlab.ui.control.DropDown
        ValueDropDownLabel        matlab.ui.control.Label
        ValueDropDown             matlab.ui.control.DropDown
        VehicleDropDownLabel      matlab.ui.control.Label
        VehicleDropDown           matlab.ui.control.DropDown
    end


    properties (Access = public)
        attribute_type=[]; 
        values=cell(1,10);
         licence_attributes = cell(1,48);
        wheel_attributes=cell(1,48);
        pedal_attributes=cell(1,48);
        down_attributes=cell(1,48);% Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fcn_get_Attributes(app);
        end

        % Button pushed function: AddButton
        function AddPushed(app, event)
            attr = app.AttributeDropDown.Value;
             val = app.ValueDropDown.Value;
             fcn_set_coordinateAttributes(app,attr,val);
             msgbox('Attribute Added!');
        end

        % Button pushed function: SaveButton
        function SavePushed(app, event)
            fcn_add_newMissingCoordinate(app);
            msgbox('New Coordinate Added!');
        end

        % Value changed function: AttributeDropDown
        function AttributeDropDownValueChanged(app, event)
            drpvalue = app.AttributeDropDown.Value;
            value_matrix = fcn_get_valueAttributes(drpvalue);
            app.ValueDropDown.Items = value_matrix;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddNewCoordinateUIFigure = uifigure;
            app.AddNewCoordinateUIFigure.Name = 'Add New Coordinate';
            setAutoResize(app, app.AddNewCoordinateUIFigure, true)
            app.value = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.x1 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.x2 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.y1 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.y2 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.z1 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.z2 = uieditfield(app.AddNewCoordinateUIFigure, 'numeric');
            app.ValueLabel = uilabel(app.AddNewCoordinateUIFigure);
            app.ValueLabel.Text = 'Value';
            app.x1Label = uilabel(app.AddNewCoordinateUIFigure);
            app.x1Label.Text = 'x1';
            app.x2Label = uilabel(app.AddNewCoordinateUIFigure);
            app.x2Label.Text = 'x2';
            app.y1Label = uilabel(app.AddNewCoordinateUIFigure);
            app.y1Label.Text = 'y1';
            app.y2Label = uilabel(app.AddNewCoordinateUIFigure);
            app.y2Label.Text = 'y2';
            app.z1Label = uilabel(app.AddNewCoordinateUIFigure);
            app.z1Label.Text = 'z1';
            app.z2Label = uilabel(app.AddNewCoordinateUIFigure);
            app.z2Label.Text = 'z2';
            app.AddButton = uibutton(app.AddNewCoordinateUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddPushed, true);
            app.AddButton.Text = 'Add';
            app.SaveButton = uibutton(app.AddNewCoordinateUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.AddNewCoordinateUIFigure);
            app.Label.Text = 'Please Select Attributes by selecting click "Add". You can save it with "Save" button';
            app.AttributeDropDownLabel = uilabel(app.AddNewCoordinateUIFigure);
            app.AttributeDropDownLabel.Text = 'Attribute';
            app.AttributeDropDown = uidropdown(app.AddNewCoordinateUIFigure);
            app.AttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @AttributeDropDownValueChanged, true);
            app.ValueDropDownLabel = uilabel(app.AddNewCoordinateUIFigure);
            app.ValueDropDownLabel.Text = 'Value';
            app.ValueDropDown = uidropdown(app.AddNewCoordinateUIFigure);
            app.VehicleDropDownLabel = uilabel(app.AddNewCoordinateUIFigure);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.AddNewCoordinateUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = newCoordinate()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddNewCoordinateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddNewCoordinateUIFigure)
        end
    end
end